classdef KRAPEKAR_en_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        CalcularKrapekarButton  matlab.ui.control.Button
        Ingresanumerode4cifrasEditFieldLabel  matlab.ui.control.Label
        Ingresanumerode4cifrasEditField  matlab.ui.control.EditField
        ResultadoTextAreaLabel  matlab.ui.control.Label
        ResultadoTextArea       matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularKrapekarButton
        function calcularKrapekar(app, event)
           %%disp(" MINUENDO    SUSTRAENDO    DIFERENCIA"); 
           res="";
          res=res+ (" MINUENDO    SUSTRAENDO    DIFERENCIA      ");
           b= num2str(app.Ingresanumerode4cifrasEditField.Value)-'0' ;
           
           sMatriz =str2num(mat2str((sort(b))));                       %matriz sustraendo de menor a mayor 
           mMatriz =str2num(mat2str(sort(sMatriz,'descend')));         %matriz minuendo de mayor a menor 
           %%resultado =resultado +  (""+b+ "   " + sort(b,'descend')+"  "+ sort(b)+""); 
           
           sustraendo = 0;
           minuendo =0;
           diferencia = 0;
           j=0;
           
           while(diferencia~=6174)                                                   %máximo de 7 veces para Kamekar 
                for i =0:3
                   sustraendo = sustraendo + sMatriz(i+1)*10^(3-i) ;    %conversion a número desde valores del array por c/ digito
                   minuendo = minuendo + mMatriz(i+1)*10^(3-i) ;        %conversion a número desde valores del array por c/ digito
            
                end
            
            diferencia = minuendo - sustraendo;                         %resta 
            j=j+1;
            dMatriz = num2str(diferencia)-'0';   
            %%disp(diferencia+ " " + minuendo+" "+ sustraendo+" ");
            res= res+(""+ minuendo+"               "+ sustraendo+"                     "+diferencia+ "                        " +" ");
            
            sMatriz =str2num(mat2str((sort(dMatriz))));                 %matriz sustraendo de menor a mayor 
            mMatriz =str2num(mat2str(sort(sMatriz,'descend')));         %matriz minuendo de menor a mayor 
            
            sustraendo =0;
            minuendo=0;

           end
           
           app.ResultadoTextArea.Value= res + "\n Numero Krapekar encontrado en la fila "+ j;
          %% app.ResultadoTextArea.Value = (resultado);
          disp(res);
          disp("Numero Krapekar encontrado en la fila "+ j);
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalcularKrapekarButton = uibutton(app.UIFigure, 'push');
            app.CalcularKrapekarButton.ButtonPushedFcn = createCallbackFcn(app, @calcularKrapekar, true);
            app.CalcularKrapekarButton.Text = 'Calcular Krapekar';
            app.Ingresanumerode4cifrasEditFieldLabel = uilabel(app.UIFigure);
            app.Ingresanumerode4cifrasEditFieldLabel.Text = 'Ingresa numero de 4 cifras';
            app.Ingresanumerode4cifrasEditField = uieditfield(app.UIFigure, 'text');
            app.ResultadoTextAreaLabel = uilabel(app.UIFigure);
            app.ResultadoTextAreaLabel.Text = {'Resultado'; ''};
            app.ResultadoTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KRAPEKAR_en_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end